classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CollisionCheckBox        matlab.ui.control.CheckBox
        UpdateSlidersButton      matlab.ui.control.Button
        Dq5Slider                matlab.ui.control.Slider
        Dq5SliderLabel           matlab.ui.control.Label
        Dq4Slider                matlab.ui.control.Slider
        Dq4SliderLabel           matlab.ui.control.Label
        Dq3Slider                matlab.ui.control.Slider
        Dq3SliderLabel           matlab.ui.control.Label
        Dq2Slider                matlab.ui.control.Slider
        Dq2SliderLabel           matlab.ui.control.Label
        Dq1Slider                matlab.ui.control.Slider
        Dq1SliderLabel           matlab.ui.control.Label
        Kq7Slider                matlab.ui.control.Slider
        Kq7SliderLabel           matlab.ui.control.Label
        Kq6Slider                matlab.ui.control.Slider
        Kq6SliderLabel           matlab.ui.control.Label
        Kq5Slider                matlab.ui.control.Slider
        Kq5SliderLabel           matlab.ui.control.Label
        Kq4Slider                matlab.ui.control.Slider
        Kq4SliderLabel           matlab.ui.control.Label
        Kq3Slider                matlab.ui.control.Slider
        Kq3SliderLabel           matlab.ui.control.Label
        Kq2Slider                matlab.ui.control.Slider
        Kq2SliderLabel           matlab.ui.control.Label
        Kq1Slider                matlab.ui.control.Slider
        Kq1SliderLabel           matlab.ui.control.Label
        DoBotControllerCheckBox  matlab.ui.control.CheckBox
        KUKAControllerCheckBox   matlab.ui.control.CheckBox
        EnvironmentButton        matlab.ui.control.Button
        DoBotZSpinner            matlab.ui.control.Spinner
        DoBotZSpinnerLabel       matlab.ui.control.Label
        DoBotYSpinner            matlab.ui.control.Spinner
        DoBotYSpinnerLabel       matlab.ui.control.Label
        DoBotXSpinner            matlab.ui.control.Spinner
        DoBotXSpinnerLabel       matlab.ui.control.Label
        KUKAZSpinner             matlab.ui.control.Spinner
        KUKAZSpinnerLabel        matlab.ui.control.Label
        KUKAYSpinner             matlab.ui.control.Spinner
        KUKAYSpinnerLabel        matlab.ui.control.Label
        KUKAXSpinner             matlab.ui.control.Spinner
        KUKAXSpinnerLabel        matlab.ui.control.Label
        BleachToiletButton       matlab.ui.control.Button
        PlungeToiletButton       matlab.ui.control.Button
        ResetCheckBox            matlab.ui.control.CheckBox
        EMERGENCYSTOPButton      matlab.ui.control.Button
    end


    properties (Access = private)
        modelKUKA % KUKA model location
        modelDoBot % DoBot model location
        items % Item locations
        environment % Environment location
        stopFlag = false; % Stop flag to be called every time robot moves
        collision = false; % Used to turn on collision detection 
        KsliderArray; % Used to easily update the sliders
        DsliderArray; % Used to easily update the sliders
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            close all
            figure;
            
            % Setup robots in the file properties
            app.modelKUKA = KUKAiiwa7;
            app.modelDoBot = DobotMagician(transl(0.5,0,0.5));
            app.modelDoBot.model.qlim(3,:) = [deg2rad(-5) deg2rad(90)];
            app.items = RobotItem(2);
            drawnow();

            % Load in the robots and set axis
            Tr = app.modelKUKA.model.fkine(app.modelKUKA.model.getpos).T;
            UpdateSpinnerValues(Tr,app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
            Tr = app.modelDoBot.model.fkine(app.modelDoBot.model.getpos).T;
            UpdateSpinnerValues(Tr,app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
            axis([-1 1 -1 1 0 1.5])
            view(15,20)
            K_qlim = app.modelKUKA.model.qlim;
            D_qlim = app.modelDoBot.model.qlim;

            % Set KUKA slider values
            app.Kq1Slider.Limits = rad2deg(K_qlim(1,:));
            app.Kq1Slider.MajorTicks = linspace(rad2deg(K_qlim(1,1)),rad2deg(K_qlim(1,2)),5);
            app.Kq2Slider.Limits = rad2deg(K_qlim(2,:));
            app.Kq2Slider.MajorTicks = linspace(rad2deg(K_qlim(2,1)),rad2deg(K_qlim(2,2)),5);
            app.Kq3Slider.Limits = rad2deg(K_qlim(3,:));
            app.Kq3Slider.MajorTicks = linspace(rad2deg(K_qlim(3,1)),rad2deg(K_qlim(3,2)),5);
            app.Kq4Slider.Limits = rad2deg(K_qlim(4,:));
            app.Kq4Slider.MajorTicks = linspace(rad2deg(K_qlim(4,1)),rad2deg(K_qlim(4,2)),5);
            app.Kq5Slider.Limits = rad2deg(K_qlim(5,:));
            app.Kq5Slider.MajorTicks = linspace(rad2deg(K_qlim(5,1)),rad2deg(K_qlim(5,2)),5);
            app.Kq6Slider.Limits = rad2deg(K_qlim(6,:));
            app.Kq6Slider.MajorTicks = linspace(rad2deg(K_qlim(6,1)),rad2deg(K_qlim(6,2)),5);
            app.Kq7Slider.Limits = rad2deg(K_qlim(7,:));
            app.Kq7Slider.MajorTicks = linspace(rad2deg(K_qlim(7,1)),rad2deg(K_qlim(7,2)),5);

            % Set dobot slider values
            app.Dq1Slider.Limits = rad2deg(D_qlim(1,:));
            app.Dq1Slider.MajorTicks = linspace(rad2deg(D_qlim(1,1)),rad2deg(D_qlim(1,2)),5);
            app.Dq2Slider.Limits = rad2deg(D_qlim(2,:));
            app.Dq2Slider.MajorTicks = linspace(rad2deg(D_qlim(2,1)),rad2deg(D_qlim(2,2)),5);
            app.Dq3Slider.Limits = rad2deg(D_qlim(3,:));
            app.Dq3Slider.MajorTicks = linspace(rad2deg(D_qlim(3,1)),rad2deg(D_qlim(3,2)),5);
            app.Dq4Slider.Limits = rad2deg(D_qlim(4,:));
            app.Dq4Slider.MajorTicks = linspace(rad2deg(D_qlim(4,1)),rad2deg(D_qlim(4,2)),5);
            app.Dq5Slider.Limits = rad2deg(D_qlim(5,:));
            app.Dq5Slider.MajorTicks = linspace(rad2deg(D_qlim(5,1)),rad2deg(D_qlim(5,2)),5);

            app.KsliderArray = [app.Kq1Slider,app.Kq2Slider,app.Kq3Slider,app.Kq4Slider,app.Kq5Slider,app.Kq6Slider,app.Kq7Slider];
            app.DsliderArray = [app.Dq1Slider,app.Dq2Slider,app.Dq3Slider,app.Dq4Slider,app.Dq5Slider];
            updateSlider(app.modelKUKA,app.KsliderArray)
            updateSlider(app.modelDoBot,app.DsliderArray)
        end

        % Button pushed function: PlungeToiletButton
        function PlungeToiletButtonPushed(app, event)
            % Dobot collects plunger
            DqMatrix = moveDoBotToItem(app.modelDoBot,app.items,1);
            for i = 1:size(DqMatrix,1)
                if app.stopFlag
                    break
                end
                TrD = app.modelDoBot.model.fkineUTS(app.modelDoBot.model.getpos);
                app.modelDoBot.model.animate(DqMatrix(i,:))
                UpdateSpinnerValues(TrD,app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
                drawnow();
            end
            
            % Before KUKA has collected Plunger
            % DoBot qMatrix
            DqMatrix = DoBotDoGood(app.modelDoBot);
            % KUKA qMatrix
            KqMatrix = KUKAtoTransferPoint(app.modelKUKA);
            if app.collision
                result = plyCollision(app.modelKUKA,KqMatrix,app.environment);
                if result
                    app.ResetCheckBox.Value = false;
                    app.stopFlag = true;
                end
            end
            for i = 1:size(KqMatrix,1)
                if app.stopFlag
                    break
                end
                app.modelKUKA.model.animate(KqMatrix(i,:))
                TrK = app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos);
                if i <= size(DqMatrix,1)
                    app.modelDoBot.model.animate(DqMatrix(i,:))
                    TrD = app.modelDoBot.model.fkineUTS(app.modelDoBot.model.getpos);
                    app.items.itemModel{1}.base = TrD * transl(0.1,-0.4,0.05)*trotx(-pi/2);
                    app.items.itemModel{1}.plot(0, 'delay', 0.01)
                    UpdateSpinnerValues(TrD,app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
                else
                    drawnow();
                    pause(0.01)
                end
                UpdateSpinnerValues(TrK,app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
            end

            
            % After Robot has collected Plunger
            KqMatrix = moveToToilet(app.modelKUKA);
            KqMatrix_reverse = KqMatrix;
            count = 1;
            for i = size(KqMatrix,1):-1:1
                KqMatrix_reverse(count,:) = KqMatrix(i,:);
                count = count+1;
            end
            KqMatrix = [KqMatrix;KUKAplungeToilet(app.modelKUKA,KqMatrix(end,:));KqMatrix_reverse];
            if app.collision
                result = plyCollision(app.modelKUKA,KqMatrix,app.environment);
                if result
                    app.ResetCheckBox.Value = false;
                    app.stopFlag = true;
                end
            end
            for i = 1:size(KqMatrix,1)
                if app.stopFlag
                    break
                end
                app.modelKUKA.model.animate(KqMatrix(i,:))
                TrK = app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos);
                app.items.itemModel{1}.base = TrK * troty(-pi/2)*trotz(pi)*transl(0.05,-0.07,-0.4);
                app.items.itemModel{1}.plot(0, 'delay', 0.01)
                UpdateSpinnerValues(TrK,app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
            end

            % DoBot returns the plunger
            for i = size(DqMatrix,1):-1:1
                if app.stopFlag
                    break
                end
                app.modelDoBot.model.animate(DqMatrix(i,:))
                TrD = app.modelDoBot.model.fkineUTS(app.modelDoBot.model.getpos);
                app.items.itemModel{1}.base = TrD * transl(0.1,-0.4,0.05)*trotx(-pi/2);
                app.items.itemModel{1}.plot(0, 'delay', 0.01)
                UpdateSpinnerValues(TrD,app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
            end
            
        end

        % Button pushed function: BleachToiletButton
        function BleachToiletButtonPushed(app, event)
            % Dobot collects bleach            
            DqMatrix = moveDoBotToItem(app.modelDoBot,app.items,2);
            for i = 1:size(DqMatrix,1)
                if app.stopFlag
                    break
                end
                app.modelDoBot.model.animate(DqMatrix(i,:))
                drawnow();
            end

            % Before Robot has collected Bleach
            % DoBot qMatrix
            DqMatrix = DoBotDoGood(app.modelDoBot);
            % KUKA qMatrix
            KqMatrix = KUKAtoTransferPoint(app.modelKUKA);
            for i = 1:size(KqMatrix,1)       
                if app.stopFlag
                    break
                end
                app.modelKUKA.model.animate(KqMatrix(i,:))
                TrK = app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos);
                if i <= size(DqMatrix,1)
                    app.modelDoBot.model.animate(DqMatrix(i,:))
                    TrD = app.modelDoBot.model.fkineUTS(app.modelDoBot.model.getpos);
                    app.items.itemModel{2}.base = TrD * transl(0,0,-0.2);
                    app.items.itemModel{2}.plot(0, 'delay', 0.01)
                    UpdateSpinnerValues(TrD,app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
                else
                    drawnow();
                    pause(0.01)
                end
                UpdateSpinnerValues(TrK,app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
            end


            % After Robot has collected Bleach
            KqMatrix = moveToToilet(app.modelKUKA);
            KqMatrix = [KqMatrix;KUKAbleachToilet(app.modelKUKA,KqMatrix(end,:))];
            if app.collision
                result = plyCollision(app.modelKUKA,KqMatrix,app.environment);
                if result
                    app.ResetCheckBox.Value = false;
                    app.stopFlag = true;
                end
            end
            for i = 1:size(KqMatrix,1)
                if app.stopFlag
                    break
                end
                app.modelKUKA.model.animate(KqMatrix(i,:))
                TrK = app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos);
                app.items.itemModel{2}.base = TrK * troty(pi/2)*transl(-0.02,0,-0.15);
                app.items.itemModel{2}.plot(0, 'delay', 0.001)

                UpdateSpinnerValues(TrK,app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
            end

            % KUKA returns the bleach
            KqMatrix = KUKAtoTransferPoint(app.modelKUKA);
            for i = 1:size(KqMatrix,1)
                if app.stopFlag
                    break
                end
                app.modelKUKA.model.animate(KqMatrix(i,:))
                TrK = app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos);
                app.items.itemModel{2}.base = TrK * troty(pi/2)*transl(-0.02,0,-0.15);
                app.items.itemModel{2}.plot(0, 'delay', 0.001)

                UpdateSpinnerValues(TrK,app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
            end

            % DoBot returns the bleach
            for i = size(DqMatrix,1):-1:1
                if app.stopFlag
                    break
                end
                app.modelDoBot.model.animate(DqMatrix(i,:))
                TrD = app.modelDoBot.model.fkineUTS(app.modelDoBot.model.getpos);
                app.items.itemModel{2}.base = TrD * transl(0,0,-0.2);
                app.items.itemModel{2}.plot(0, 'delay', 0.01)
                UpdateSpinnerValues(TrD,app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
            end
            
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            if app.ResetCheckBox.Value
                app.ResetCheckBox.Value = false;
                app.stopFlag = true;
            end
        end

        % Value changed function: ResetCheckBox
        function ResetCheckBoxValueChanged(app, event)
            value = app.ResetCheckBox.Value;
            app.stopFlag = ~value;
        end

        % Value changed function: KUKAXSpinner
        function KUKAXSpinnerValueChanged(app, event)
            value = app.KUKAXSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelKUKA,10,value/1000,1)
            end
        end

        % Value changed function: KUKAYSpinner
        function KUKAYSpinnerValueChanged(app, event)
            value = app.KUKAYSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelKUKA,10,value/1000,2)
            end
        end

        % Value changed function: KUKAZSpinner
        function KUKAZSpinnerValueChanged(app, event)
            value = app.KUKAZSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelKUKA,10,value/1000,3)
            end
        end

        % Value changed function: DoBotXSpinner
        function DoBotXSpinnerValueChanged(app, event)
            value = app.DoBotXSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelDoBot,10,value/1000,1)
            end
        end

        % Value changed function: DoBotYSpinner
        function DoBotYSpinnerValueChanged(app, event)
            value = app.DoBotYSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelDoBot,10,value/1000,2)
            end
        end

        % Value changed function: DoBotZSpinner
        function DoBotZSpinnerValueChanged(app, event)
            value = app.DoBotZSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelDoBot,10,value/1000,3)
            end
        end

        % Button pushed function: EnvironmentButton
        function EnvironmentButtonPushed(app, event)
            app.environment = setupEnvironment(app.modelKUKA,app.modelDoBot,app.items);
        end

        % Value changed function: KUKAControllerCheckBox
        function KUKAControllerCheckBoxValueChanged(app, event)
            value = app.KUKAControllerCheckBox.Value;
            id = 1; % Note: may need to be changed if multiple joysticks present
            try
                joy = vrjoystick(id);
            catch
                app.stopFlag = true;
                app.ResetCheckBox.Value = false;
            end

            dt = 0.1;      % Set time step for simulation (seconds)
            n = 0;  % Initialise step count to zero
            q = app.modelKUKA.model.getpos;
            while value && ~app.stopFlag
                n = n + 1;
                UpdateSpinnerValues(app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos),app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
                q = controllerInput(joy,app.modelKUKA,dt,q);
                pause(dt)
            end


        end

        % Value changed function: DoBotControllerCheckBox
        function DoBotControllerCheckBoxValueChanged(app, event)
            value = app.DoBotControllerCheckBox.Value;
            id = 1; % Note: may need to be changed if multiple joysticks present
            try
                joy = vrjoystick(id);
            catch
                app.stopFlag = true;
                app.ResetCheckBox.Value = false;
            end

            dt = 0.1;      % Set time step for simulation (seconds)
            n = 0;  % Initialise step count to zero
            q = app.modelDoBot.model.getpos;
            while value && ~app.stopFlag
                n = n + 1;
                UpdateSpinnerValues(app.modelDoBot.model.fkineUTS(app.modelDoBot.model.getpos),app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
                q = controllerInput(joy,app.modelDoBot,dt,q);
                pause(dt)
            end
        end

        % Value changing function: Kq1Slider
        function Kq1SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelKUKA,changingValue,1)
            end
            UpdateSpinnerValues(app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos),app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
        end

        % Value changing function: Kq2Slider
        function Kq2SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelKUKA,changingValue,2)
            end
            UpdateSpinnerValues(app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos),app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
        end

        % Value changing function: Kq3Slider
        function Kq3SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelKUKA,changingValue,3)
            end
            UpdateSpinnerValues(app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos),app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
        end

        % Value changing function: Kq4Slider
        function Kq4SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelKUKA,changingValue,4)
            end
            UpdateSpinnerValues(app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos),app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
        end

        % Value changing function: Kq5Slider
        function Kq5SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelKUKA,changingValue,5)
            end
            UpdateSpinnerValues(app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos),app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
        end

        % Value changing function: Kq6Slider
        function Kq6SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelKUKA,changingValue,6)
            end
            UpdateSpinnerValues(app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos),app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
        end

        % Value changing function: Kq7Slider
        function Kq7SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelKUKA,changingValue,7)
            end
            UpdateSpinnerValues(app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos),app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
        end

        % Value changing function: Dq1Slider
        function Dq1SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelDoBot,changingValue,1)
            end
            UpdateSpinnerValues(app.modelDoBot.model.fkineUTS(app.modelDoBot.model.getpos),app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
        end

        % Value changing function: Dq2Slider
        function Dq2SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelDoBot,changingValue,2)
            end
            UpdateSpinnerValues(app.modelDoBot.model.fkineUTS(app.modelDoBot.model.getpos),app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
        end

        % Value changing function: Dq3Slider
        function Dq3SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelDoBot,changingValue,3)
            end
            UpdateSpinnerValues(app.modelDoBot.model.fkineUTS(app.modelDoBot.model.getpos),app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
        end

        % Value changing function: Dq4Slider
        function Dq4SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelDoBot,changingValue,4)
            end
            UpdateSpinnerValues(app.modelDoBot.model.fkineUTS(app.modelDoBot.model.getpos),app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
        end

        % Value changing function: Dq5Slider
        function Dq5SliderValueChanging(app, event)
            changingValue = event.Value;
            if ~app.stopFlag
                jointSlider(app.modelDoBot,changingValue,5)
            end
            UpdateSpinnerValues(app.modelDoBot.model.fkineUTS(app.modelDoBot.model.getpos),app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
        end

        % Button pushed function: UpdateSlidersButton
        function UpdateSlidersButtonPushed(app, event)
            updateSlider(app.modelKUKA,app.KsliderArray)
             updateSlider(app.modelDoBot,app.DsliderArray)
        end

        % Value changed function: CollisionCheckBox
        function CollisionCheckBoxValueChanged(app, event)
            value = app.CollisionCheckBox.Value;
            app.collision = value;
            if app.collision
                result = plyCollision(app.modelKUKA,app.modelKUKA.model.getpos,app.environment);
                if result
                    app.ResetCheckBox.Value = false;
                    app.stopFlag = true;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = {'EMERGENCY'; 'STOP'};
            app.ResetCheckBox = uicheckbox(app.UIFigure);
            app.ResetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetCheckBoxValueChanged, true);
            app.ResetCheckBox.Text = 'Reset';
            app.ResetCheckBox.Value = true;
            app.PlungeToiletButton = uibutton(app.UIFigure, 'push');
            app.PlungeToiletButton.ButtonPushedFcn = createCallbackFcn(app, @PlungeToiletButtonPushed, true);
            app.PlungeToiletButton.Text = 'Plunge Toilet';
            app.BleachToiletButton = uibutton(app.UIFigure, 'push');
            app.BleachToiletButton.ButtonPushedFcn = createCallbackFcn(app, @BleachToiletButtonPushed, true);
            app.BleachToiletButton.Text = 'Bleach Toilet';
            app.KUKAXSpinnerLabel = uilabel(app.UIFigure);
            app.KUKAXSpinnerLabel.Text = 'KUKA X';
            app.KUKAXSpinner = uispinner(app.UIFigure);
            app.KUKAXSpinner.ValueChangedFcn = createCallbackFcn(app, @KUKAXSpinnerValueChanged, true);
            app.KUKAYSpinnerLabel = uilabel(app.UIFigure);
            app.KUKAYSpinnerLabel.Text = 'KUKA Y';
            app.KUKAYSpinner = uispinner(app.UIFigure);
            app.KUKAYSpinner.ValueChangedFcn = createCallbackFcn(app, @KUKAYSpinnerValueChanged, true);
            app.KUKAZSpinnerLabel = uilabel(app.UIFigure);
            app.KUKAZSpinnerLabel.Text = 'KUKA Z';
            app.KUKAZSpinner = uispinner(app.UIFigure);
            app.KUKAZSpinner.ValueChangedFcn = createCallbackFcn(app, @KUKAZSpinnerValueChanged, true);
            app.DoBotXSpinnerLabel = uilabel(app.UIFigure);
            app.DoBotXSpinnerLabel.Text = 'DoBot X';
            app.DoBotXSpinner = uispinner(app.UIFigure);
            app.DoBotXSpinner.ValueChangedFcn = createCallbackFcn(app, @DoBotXSpinnerValueChanged, true);
            app.DoBotYSpinnerLabel = uilabel(app.UIFigure);
            app.DoBotYSpinnerLabel.Text = 'DoBot Y';
            app.DoBotYSpinner = uispinner(app.UIFigure);
            app.DoBotYSpinner.ValueChangedFcn = createCallbackFcn(app, @DoBotYSpinnerValueChanged, true);
            app.DoBotZSpinnerLabel = uilabel(app.UIFigure);
            app.DoBotZSpinnerLabel.Text = 'DoBot Z';
            app.DoBotZSpinner = uispinner(app.UIFigure);
            app.DoBotZSpinner.ValueChangedFcn = createCallbackFcn(app, @DoBotZSpinnerValueChanged, true);
            app.EnvironmentButton = uibutton(app.UIFigure, 'push');
            app.EnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentButtonPushed, true);
            app.EnvironmentButton.Text = 'Environment';
            app.KUKAControllerCheckBox = uicheckbox(app.UIFigure);
            app.KUKAControllerCheckBox.ValueChangedFcn = createCallbackFcn(app, @KUKAControllerCheckBoxValueChanged, true);
            app.KUKAControllerCheckBox.Text = 'KUKA Controller';
            app.DoBotControllerCheckBox = uicheckbox(app.UIFigure);
            app.DoBotControllerCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoBotControllerCheckBoxValueChanged, true);
            app.DoBotControllerCheckBox.Text = 'DoBot Controller';
            app.Kq1SliderLabel = uilabel(app.UIFigure);
            app.Kq1SliderLabel.Text = 'Kq1';
            app.Kq1Slider = uislider(app.UIFigure);
            app.Kq1Slider.ValueChangingFcn = createCallbackFcn(app, @Kq1SliderValueChanging, true);
            app.Kq2SliderLabel = uilabel(app.UIFigure);
            app.Kq2SliderLabel.Text = 'Kq2';
            app.Kq2Slider = uislider(app.UIFigure);
            app.Kq2Slider.ValueChangingFcn = createCallbackFcn(app, @Kq2SliderValueChanging, true);
            app.Kq3SliderLabel = uilabel(app.UIFigure);
            app.Kq3SliderLabel.Text = 'Kq3';
            app.Kq3Slider = uislider(app.UIFigure);
            app.Kq3Slider.ValueChangingFcn = createCallbackFcn(app, @Kq3SliderValueChanging, true);
            app.Kq4SliderLabel = uilabel(app.UIFigure);
            app.Kq4SliderLabel.Text = 'Kq4';
            app.Kq4Slider = uislider(app.UIFigure);
            app.Kq4Slider.ValueChangingFcn = createCallbackFcn(app, @Kq4SliderValueChanging, true);
            app.Kq5SliderLabel = uilabel(app.UIFigure);
            app.Kq5SliderLabel.Text = 'Kq5';
            app.Kq5Slider = uislider(app.UIFigure);
            app.Kq5Slider.ValueChangingFcn = createCallbackFcn(app, @Kq5SliderValueChanging, true);
            app.Kq6SliderLabel = uilabel(app.UIFigure);
            app.Kq6SliderLabel.Text = 'Kq6';
            app.Kq6Slider = uislider(app.UIFigure);
            app.Kq6Slider.ValueChangingFcn = createCallbackFcn(app, @Kq6SliderValueChanging, true);
            app.Kq7SliderLabel = uilabel(app.UIFigure);
            app.Kq7SliderLabel.Text = 'Kq7';
            app.Kq7Slider = uislider(app.UIFigure);
            app.Kq7Slider.ValueChangingFcn = createCallbackFcn(app, @Kq7SliderValueChanging, true);
            app.Dq1SliderLabel = uilabel(app.UIFigure);
            app.Dq1SliderLabel.Text = 'Dq1';
            app.Dq1Slider = uislider(app.UIFigure);
            app.Dq1Slider.ValueChangingFcn = createCallbackFcn(app, @Dq1SliderValueChanging, true);
            app.Dq2SliderLabel = uilabel(app.UIFigure);
            app.Dq2SliderLabel.Text = 'Dq2';
            app.Dq2Slider = uislider(app.UIFigure);
            app.Dq2Slider.ValueChangingFcn = createCallbackFcn(app, @Dq2SliderValueChanging, true);
            app.Dq3SliderLabel = uilabel(app.UIFigure);
            app.Dq3SliderLabel.Text = 'Dq3';
            app.Dq3Slider = uislider(app.UIFigure);
            app.Dq3Slider.ValueChangingFcn = createCallbackFcn(app, @Dq3SliderValueChanging, true);
            app.Dq4SliderLabel = uilabel(app.UIFigure);
            app.Dq4SliderLabel.Text = 'Dq4';
            app.Dq4Slider = uislider(app.UIFigure);
            app.Dq4Slider.ValueChangingFcn = createCallbackFcn(app, @Dq4SliderValueChanging, true);
            app.Dq5SliderLabel = uilabel(app.UIFigure);
            app.Dq5SliderLabel.Text = 'Dq5';
            app.Dq5Slider = uislider(app.UIFigure);
            app.Dq5Slider.ValueChangingFcn = createCallbackFcn(app, @Dq5SliderValueChanging, true);
            app.UpdateSlidersButton = uibutton(app.UIFigure, 'push');
            app.UpdateSlidersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSlidersButtonPushed, true);
            app.UpdateSlidersButton.Text = 'Update Sliders';
            app.CollisionCheckBox = uicheckbox(app.UIFigure);
            app.CollisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CollisionCheckBoxValueChanged, true);
            app.CollisionCheckBox.Text = 'Collision';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end