classdef TestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EnvironmentButton         matlab.ui.control.Button
        DoBotZSpinner             matlab.ui.control.Spinner
        DoBotZSpinnerLabel        matlab.ui.control.Label
        DoBotYSpinner             matlab.ui.control.Spinner
        DoBotYSpinnerLabel        matlab.ui.control.Label
        DoBotXSpinner             matlab.ui.control.Spinner
        DoBotXSpinnerLabel        matlab.ui.control.Label
        KUKAZSpinner              matlab.ui.control.Spinner
        KUKAZSpinnerLabel         matlab.ui.control.Label
        KUKAYSpinner              matlab.ui.control.Spinner
        KUKAYSpinnerLabel         matlab.ui.control.Label
        KUKAXSpinner              matlab.ui.control.Spinner
        KUKAXSpinnerLabel         matlab.ui.control.Label
        DoBotCompleteTaskAButton  matlab.ui.control.Button
        DoBotCompleteTaskBButton  matlab.ui.control.Button
        KUKACompleteTaskBButton   matlab.ui.control.Button
        KUKACompleteTaskAButton   matlab.ui.control.Button
        ResetCheckBox             matlab.ui.control.CheckBox
        EMERGENCYSTOPButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        modelKUKA % KUKA model location
        modelDoBot % DoBot model location
        qn_K = zeros(1,7);
        qn_D = zeros(1,4);
        stopFlag = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            close all
            figure;
            app.modelKUKA = KUKAiiwa7;
            app.modelDoBot = DobotMagician(transl(0.5,0,0.5));
            drawnow();
            Tr = app.modelKUKA.model.fkine(app.modelKUKA.model.getpos).T;
            UpdateSpinnerValues(Tr,app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
            Tr = app.modelDoBot.model.fkine(app.modelDoBot.model.getpos).T;
            UpdateSpinnerValues(Tr,app.DoBotXSpinner,app.DoBotYSpinner,app.DoBotZSpinner)
            axis([-1 1 -1 1 0 1.5])
            view(3)
        end

        % Button pushed function: KUKACompleteTaskAButton
        function KUKACompleteTaskAButtonPushed(app, event)
            q1 = app.modelKUKA.model.getpos;
            q2 = [2.4511   -0.8651    2.3865   -1.2566    1.1868    1.0891   -1.2828];
            qMatrix = jtraj(q1,q2,100);
            for i = 1:100
                app.modelKUKA.model.animate(qMatrix(i,:))
                if app.stopFlag
                    break
                end
                drawnow();
            end
            UpdateSpinnerValues(app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos),app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
        end

        % Button pushed function: KUKACompleteTaskBButton
        function KUKACompleteTaskBButtonPushed(app, event)
            q1 = app.modelKUKA.model.getpos;
            q2 = [1.1610    1.2566   -1.1868    1.0891    2.3736    0.8378    1.2217];
            qMatrix = jtraj(q1,q2,100);
            for i = 1:100
                app.modelKUKA.model.animate(qMatrix(i,:))
                if app.stopFlag
                    break
                end
                drawnow();
            end
            UpdateSpinnerValues(app.modelKUKA.model.fkineUTS(app.modelKUKA.model.getpos),app.KUKAXSpinner,app.KUKAYSpinner,app.KUKAZSpinner)
        end

        % Value changed function: KUKAXSpinner
        function KUKAXSpinnerValueChanged(app, event)
            value = app.KUKAXSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelKUKA,10,value/1000,1)
            end
        end

        % Value changed function: KUKAYSpinner
        function KUKAYSpinnerValueChanged(app, event)
            value = app.KUKAYSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelKUKA,10,value/1000,2)
            end
        end

        % Value changed function: KUKAZSpinner
        function KUKAZSpinnerValueChanged(app, event)
            value = app.KUKAZSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelKUKA,10,value/1000,3)
            end
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            if app.ResetCheckBox.Value
                app.ResetCheckBox.Value = false;
                app.stopFlag = true;
            end 
        end

        % Value changed function: ResetCheckBox
        function ResetCheckBoxValueChanged(app, event)
            value = app.ResetCheckBox.Value;
            app.stopFlag = ~value;
        end

        % Value changed function: DoBotXSpinner
        function DoBotXSpinnerValueChanged(app, event)
            value = app.DoBotXSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelDoBot,10,value/1000,1)
            end
        end

        % Value changed function: DoBotYSpinner
        function DoBotYSpinnerValueChanged(app, event)
            value = app.DoBotYSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelDoBot,10,value/1000,2)
            end
        end

        % Value changed function: DoBotZSpinner
        function DoBotZSpinnerValueChanged(app, event)
            value = app.DoBotZSpinner.Value;
            if ~app.stopFlag
                RMRC_oneDirection(app.modelDoBot,10,value/1000,3)
            end
        end

        % Button pushed function: EnvironmentButton
        function EnvironmentButtonPushed(app, event)
            setupEnvironment(app.modelKUKA,app.modelDoBot)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = {'EMERGENCY'; 'STOP'};
            app.ResetCheckBox = uicheckbox(app.UIFigure);
            app.ResetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetCheckBoxValueChanged, true);
            app.ResetCheckBox.Text = 'Reset';
            app.ResetCheckBox.Value = true;
            app.KUKACompleteTaskAButton = uibutton(app.UIFigure, 'push');
            app.KUKACompleteTaskAButton.ButtonPushedFcn = createCallbackFcn(app, @KUKACompleteTaskAButtonPushed, true);
            app.KUKACompleteTaskAButton.Text = {'KUKA'; 'Complete Task A'};
            app.KUKACompleteTaskBButton = uibutton(app.UIFigure, 'push');
            app.KUKACompleteTaskBButton.ButtonPushedFcn = createCallbackFcn(app, @KUKACompleteTaskBButtonPushed, true);
            app.KUKACompleteTaskBButton.Text = {'KUKA'; 'Complete Task B'};
            app.DoBotCompleteTaskBButton = uibutton(app.UIFigure, 'push');
            app.DoBotCompleteTaskBButton.Text = {'DoBot'; 'Complete Task B'};
            app.DoBotCompleteTaskAButton = uibutton(app.UIFigure, 'push');
            app.DoBotCompleteTaskAButton.Text = {'DoBot'; 'Complete Task A'};
            app.KUKAXSpinnerLabel = uilabel(app.UIFigure);
            app.KUKAXSpinnerLabel.Text = 'KUKA X';
            app.KUKAXSpinner = uispinner(app.UIFigure);
            app.KUKAXSpinner.ValueChangedFcn = createCallbackFcn(app, @KUKAXSpinnerValueChanged, true);
            app.KUKAYSpinnerLabel = uilabel(app.UIFigure);
            app.KUKAYSpinnerLabel.Text = 'KUKA Y';
            app.KUKAYSpinner = uispinner(app.UIFigure);
            app.KUKAYSpinner.ValueChangedFcn = createCallbackFcn(app, @KUKAYSpinnerValueChanged, true);
            app.KUKAZSpinnerLabel = uilabel(app.UIFigure);
            app.KUKAZSpinnerLabel.Text = 'KUKA Z';
            app.KUKAZSpinner = uispinner(app.UIFigure);
            app.KUKAZSpinner.ValueChangedFcn = createCallbackFcn(app, @KUKAZSpinnerValueChanged, true);
            app.DoBotXSpinnerLabel = uilabel(app.UIFigure);
            app.DoBotXSpinnerLabel.Text = 'DoBot X';
            app.DoBotXSpinner = uispinner(app.UIFigure);
            app.DoBotXSpinner.ValueChangedFcn = createCallbackFcn(app, @DoBotXSpinnerValueChanged, true);
            app.DoBotYSpinnerLabel = uilabel(app.UIFigure);
            app.DoBotYSpinnerLabel.Text = 'DoBot Y';
            app.DoBotYSpinner = uispinner(app.UIFigure);
            app.DoBotYSpinner.ValueChangedFcn = createCallbackFcn(app, @DoBotYSpinnerValueChanged, true);
            app.DoBotZSpinnerLabel = uilabel(app.UIFigure);
            app.DoBotZSpinnerLabel.Text = 'DoBot Z';
            app.DoBotZSpinner = uispinner(app.UIFigure);
            app.DoBotZSpinner.ValueChangedFcn = createCallbackFcn(app, @DoBotZSpinnerValueChanged, true);
            app.EnvironmentButton = uibutton(app.UIFigure, 'push');
            app.EnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentButtonPushed, true);
            app.EnvironmentButton.Text = 'Environment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end